classdef casting_orientation_optimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        ImageUIAxes                   matlab.ui.control.UIAxes
        ParallelCoordinatesUIAxes     matlab.ui.control.UIAxes
        DataSelectionButtonGroup      matlab.ui.container.ButtonGroup
        SingleButton                  matlab.ui.control.RadioButton
        FeasibilityViewButton         matlab.ui.control.RadioButton
        ObjectiveDropDown             matlab.ui.control.DropDown
        ShowThresholdCheckBox         matlab.ui.control.CheckBox
        DataModeButtonGroup           matlab.ui.container.ButtonGroup
        ValuesButton                  matlab.ui.control.RadioButton
        QuantilesButton               matlab.ui.control.RadioButton
        PointDisplayPanel             matlab.ui.container.Panel
        ParetoFrontCheckBox           matlab.ui.control.CheckBox
        GlobalMinimumCheckBox         matlab.ui.control.CheckBox
        DrawSelectedPointButton       matlab.ui.control.Button
        RotationXSpinner              matlab.ui.control.Spinner
        RotationXSlider               matlab.ui.control.Slider
        RotationXLabel                matlab.ui.control.Label
        RotationYSpinner              matlab.ui.control.Spinner
        RotationYSlider               matlab.ui.control.Slider
        RotationYLabel                matlab.ui.control.Label
        SelectedPointclickonmapLabel  matlab.ui.control.Label
        FeasibilityThresholdSelectionPanel  matlab.ui.container.Panel
        ObjectiveCheckBox             matlab.ui.control.CheckBox
        Spinner                       matlab.ui.control.Spinner
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        root_path
        orientation_data_model
        data_mode_controller
        data_selection_controller
        feasibility_threshold_selection_controller
        image_plot_controller
        parallel_coordinates_plot_controller
        point_display_controller
    end
    
    properties ( Access = private, Constant )
        APP_TITLE(1,1) string = "Casting Orientation Optimization"
    end
    
    methods (Access = private)
        function update_title( app, file_name )
            if nargin < 2
                file_name = [];
            end
            
            assert( isstring( file_name ) );
            
            if ~isempty( file_name )
                app.UIFigure.Name = app.APP_TITLE + ": " + file_name;
            end
        end
        
        % TODO R2019a fold these into UIFigureButtonDown or whatever
        function image_plot_clicked( app, ~, event )
            point = event.IntersectionPoint;
            point = point( 1 : 2 );
            app.image_plot_controller.update_clicked( point );
            app.point_display_controller.update_selected_point_by_click();
            drawnow();
        end
        
        % TODO R2019a fold these into UIFigureButtonDown or whatever
        function parallel_coordinates_plot_clicked( app, ~, event )
            point = event.IntersectionPoint;
            point = point( 1 : 2 );
            app.parallel_coordinates_plot_controller.update_clicked( point );
            drawnow();
            app.feasibility_threshold_selection_controller.update_from_external();
            drawnow();
            app.parallel_coordinates_plot_controller.update_lines();
            drawnow();
            app.image_plot_controller.update_pareto_front();
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.root_path = fileparts( mfilename( 'fullpath' ) );
            
            odm = OrientationDataModel();
            
            % DATA SELECTION PANEL
            app.SingleButton.Tag = odm.SINGLE_VIEW;
            app.FeasibilityViewButton.Tag = odm.FEASIBILITY_VIEW;
            app.data_selection_controller = DataSelectionController( ...
                app.DataSelectionButtonGroup, ...
                app.ObjectiveDropDown, ...
                app.ShowThresholdCheckBox, ...
                odm ...
                );
            app.data_selection_controller.update_view_selection();
            app.data_selection_controller.update_objective_selection();
            app.data_selection_controller.update_threshold_check_box();
            
            % DATA MODE PANEL
            app.ValuesButton.Tag = odm.VALUES_MODE;
            app.QuantilesButton.Tag = odm.QUANTILES_MODE;
            app.data_mode_controller = DataModeController( ...
                app.DataModeButtonGroup, ...
                odm ...
                );
            app.data_mode_controller.update();
            
            % FEASIBILITY THRESHOLD SELECTION PANEL
            app.feasibility_threshold_selection_controller = FeasibilityThresholdSelectionController( ...
                app.FeasibilityThresholdSelectionPanel, ...
                app.ObjectiveCheckBox, ...
                app.Slider, ...
                app.Spinner, ...
                odm ...
                );
            
            % POINT DISPLAY PANEL
            app.point_display_controller = PointDisplayController( ...
                app.ParetoFrontCheckBox, ...
                app.GlobalMinimumCheckBox, ...
                app.RotationXSpinner, ...
                app.RotationXSlider, ...
                app.RotationYSpinner, ...
                app.RotationYSlider, ...
                odm ...
                );
            
            % IMAGE PLOT
            app.image_plot_controller = ImagePlotController( ...
                app.ImageUIAxes, ...
                odm, ...
                @app.image_plot_clicked ... % TODO R2019a
                );
            
            % PARALLEL COORDINATES PLOT
            app.parallel_coordinates_plot_controller = ParallelCoordinatesPlotController( ...
                app.ParallelCoordinatesUIAxes, ...
                odm, ...
                @app.parallel_coordinates_plot_clicked ... % TODO R2019a
                );
            
            app.orientation_data_model = odm;
            
            % load settings/selections
        end

        % Selection changed function: DataSelectionButtonGroup
        function DataSelectionButtonGroupSelectionChanged(app, event)
            app.data_selection_controller.update_view_selection();
            app.point_display_controller.update_global_minimum();
            
            if ~app.orientation_data_model.ready
                return;
            end
            
            app.feasibility_threshold_selection_controller.update_from_external();
            drawnow();
            
            app.image_plot_controller.update_pareto_front();
            app.image_plot_controller.update_global_minimum();
            drawnow();
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
            
            app.parallel_coordinates_plot_controller.update_lines();
            drawnow();
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            app.data_selection_controller.update_objective_selection();
            
            if ~app.orientation_data_model.ready
                return;
            end
            
            app.feasibility_threshold_selection_controller.update_from_external();
            drawnow();
            
            app.image_plot_controller.update_pareto_front();
            app.image_plot_controller.update_global_minimum();
            drawnow();
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
            
            app.parallel_coordinates_plot_controller.update_minimal();
            drawnow();
        end

        % Value changed function: ShowThresholdCheckBox
        function ShowThresholdCheckBoxValueChanged(app, event)
            app.data_selection_controller.update_threshold_check_box();
            drawnow();
            
            if ~app.orientation_data_model.ready
                return;
            end
            
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
        end

        % Selection changed function: DataModeButtonGroup
        function DataModeButtonGroupSelectionChanged(app, event)
            app.data_mode_controller.update();
            
            if ~app.orientation_data_model.ready
                return;
            end
            
            app.feasibility_threshold_selection_controller.update_from_external();
            drawnow();
            
            app.image_plot_controller.update_pareto_front();
            drawnow();
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
            
            app.parallel_coordinates_plot_controller.update_values();
            drawnow();
        end

        % Value changed function: ParetoFrontCheckBox
        function ParetoFrontCheckBoxValueChanged(app, event)
            app.point_display_controller.update_pareto_front();
            
            if ~app.orientation_data_model.ready
                return;
            end
            
            app.image_plot_controller.update_pareto_front();
            drawnow();
        end

        % Value changed function: GlobalMinimumCheckBox
        function GlobalMinimumCheckBoxValueChanged(app, event)
            app.point_display_controller.update_global_minimum();
            
            if ~app.orientation_data_model.ready
                return;
            end
            
            app.image_plot_controller.update_global_minimum();
            drawnow();
        end

        % Button pushed function: DrawSelectedPointButton
        function DrawSelectedPointButtonPushed(app, event)
            app.point_display_controller.visualize_selected_point();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            objective = event.Source.Tag;
            app.feasibility_threshold_selection_controller.update_from_slider( objective );
            drawnow();
            
            app.image_plot_controller.update_pareto_front();
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
            
            app.parallel_coordinates_plot_controller.update_lines();
            drawnow();
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            objective = event.Source.Tag;
            app.feasibility_threshold_selection_controller.update_from_spinner( objective );
            drawnow();
            
            app.image_plot_controller.update_pareto_front();
            app.image_plot_controller.update_values();
            app.image_plot_controller.update_threshold_values();
            drawnow();
            
            app.parallel_coordinates_plot_controller.update_lines();
            drawnow();
        end

        % Value changed function: ObjectiveCheckBox
        function ObjectiveCheckBoxValueChanged(app, event)
            objective = event.Source.Tag;
            app.feasibility_threshold_selection_controller.update_from_checkbox( objective );
            drawnow();
            
            app.parallel_coordinates_plot_controller.update_lines();
            drawnow();
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            % TODO R2019a
%             app.image_plot_controller.update_clicked();
%             app.parallel_coordinates_plot_controller.update_clicked();
%             
%             app.point_display_controller.update_selected_point();
%             app.feasibility_threshold_selection_controller.update_from_external();
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % progress box
            dialog_handle = uiprogressdlg( app.UIFigure );
            dialog_handle.Indeterminate = "on";
            dialog_handle.Message = "Loading, thank you for waiting.";
            dialog_handle.Title = "Loading";
            dialog_handle.Cancelable = "off";
            
            try
                canceled = app.orientation_data_model.load( app.root_path );
            catch e
                disp( getReport( e ) );
                return;
            end
            
            if canceled
                return;
            end
            
            app.update_title( app.orientation_data_model.name );
            
            app.data_selection_controller.set_drop_down_items();
            app.feasibility_threshold_selection_controller.set_widgets();
            drawnow();
            
            app.data_selection_controller.update_all();
            app.data_mode_controller.update();
            app.feasibility_threshold_selection_controller.update_from_external();
            app.point_display_controller.update_all();
            drawnow();
            
            app.image_plot_controller.update_all();
            drawnow();
            
            app.parallel_coordinates_plot_controller.reset_plot();
            app.parallel_coordinates_plot_controller.update_values();
            drawnow();
            
            dialog_handle.close();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            title = "About";
            message = [
                app.APP_TITLE;
                "(c) 2016-2019 William Warriner";
                "GitHub: github.com/wwarriner";
                "github.com/wwarriner/casting_orientation_optimization";
                "github.com/wwarriner/casting_geometric_toolsuite";
                ];
            h = uiconfirm( app.UIFigure, message, title );
            h.Icon = "info";
            h.Options = "Close";
            h.DefaultOption = 1;
            h.CancelOption = 1;
        end

        % Value changed function: RotationXSpinner
        function RotationXSpinnerValueChanged(app, event)
            app.point_display_controller.update_selected_point_by_spinner( 1 );
            app.image_plot_controller.update_clicked();
            drawnow();
        end

        % Value changed function: RotationYSpinner
        function RotationYSpinnerValueChanged(app, event)
            app.point_display_controller.update_selected_point_by_spinner( 2 );
            app.image_plot_controller.update_clicked();
            drawnow();
        end

        % Value changed function: RotationXSlider
        function RotationXSliderValueChanged(app, event)
            app.point_display_controller.update_selected_point_by_slider( 1 );
            app.image_plot_controller.update_clicked();
            drawnow();
        end

        % Value changed function: RotationYSlider
        function RotationYSliderValueChanged(app, event)
            app.point_display_controller.update_selected_point_by_slider( 2 );
            app.image_plot_controller.update_clicked();
            drawnow();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load...';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.ImageUIAxes = uiaxes(app.UIFigure);
            app.ImageUIAxes.XLim = [-180 180.01];
            app.ImageUIAxes.YLim = [-90 90.01];
            app.ImageUIAxes.XAxisLocation = 'origin';
            app.ImageUIAxes.XTick = [-180 -135 -90 -45 0 45 90 135 180];
            app.ImageUIAxes.YAxisLocation = 'origin';
            app.ImageUIAxes.YTick = [-90 -45 0 45 90];
            app.ParallelCoordinatesUIAxes = uiaxes(app.UIFigure);
            app.DataSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.DataSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSelectionButtonGroupSelectionChanged, true);
            app.DataSelectionButtonGroup.Title = 'Data Selection';
            app.SingleButton = uiradiobutton(app.DataSelectionButtonGroup);
            app.SingleButton.Text = {''; ''};
            app.SingleButton.Value = true;
            app.FeasibilityViewButton = uiradiobutton(app.DataSelectionButtonGroup);
            app.FeasibilityViewButton.Text = 'Feasibility View';
            app.ObjectiveDropDown = uidropdown(app.DataSelectionButtonGroup);
            app.ObjectiveDropDown.Items = {};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Value = {};
            app.ShowThresholdCheckBox = uicheckbox(app.DataSelectionButtonGroup);
            app.ShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowThresholdCheckBoxValueChanged, true);
            app.ShowThresholdCheckBox.Text = 'Threshold';
            app.ShowThresholdCheckBox.Value = true;
            app.DataModeButtonGroup = uibuttongroup(app.UIFigure);
            app.DataModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataModeButtonGroupSelectionChanged, true);
            app.DataModeButtonGroup.Title = 'Data Mode';
            app.ValuesButton = uiradiobutton(app.DataModeButtonGroup);
            app.ValuesButton.Text = 'Values';
            app.ValuesButton.Value = true;
            app.QuantilesButton = uiradiobutton(app.DataModeButtonGroup);
            app.QuantilesButton.Text = 'Quantiles';
            app.PointDisplayPanel = uipanel(app.UIFigure);
            app.PointDisplayPanel.Title = 'Point Display';
            app.ParetoFrontCheckBox = uicheckbox(app.PointDisplayPanel);
            app.ParetoFrontCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParetoFrontCheckBoxValueChanged, true);
            app.ParetoFrontCheckBox.Text = 'Pareto Front';
            app.ParetoFrontCheckBox.Value = true;
            app.GlobalMinimumCheckBox = uicheckbox(app.PointDisplayPanel);
            app.GlobalMinimumCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalMinimumCheckBoxValueChanged, true);
            app.GlobalMinimumCheckBox.Text = 'Global Minimum';
            app.GlobalMinimumCheckBox.Value = true;
            app.DrawSelectedPointButton = uibutton(app.PointDisplayPanel, 'push');
            app.DrawSelectedPointButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSelectedPointButtonPushed, true);
            app.DrawSelectedPointButton.Text = 'Draw Selected Point...';
            app.RotationXSpinner = uispinner(app.PointDisplayPanel);
            app.RotationXSpinner.Limits = [-180 180];
            app.RotationXSpinner.ValueDisplayFormat = '%.1f';
            app.RotationXSpinner.ValueChangedFcn = createCallbackFcn(app, @RotationXSpinnerValueChanged, true);
            app.RotationXSlider = uislider(app.PointDisplayPanel);
            app.RotationXSlider.Limits = [-180 180];
            app.RotationXSlider.MajorTicks = [-180 -90 0 90 180];
            app.RotationXSlider.MajorTickLabels = {'[]'};
            app.RotationXSlider.ValueChangedFcn = createCallbackFcn(app, @RotationXSliderValueChanged, true);
            app.RotationXSlider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.RotationXLabel = uilabel(app.PointDisplayPanel);
            app.RotationXLabel.Text = '@X:';
            app.RotationYSpinner = uispinner(app.PointDisplayPanel);
            app.RotationYSpinner.Limits = [-90 90];
            app.RotationYSpinner.ValueDisplayFormat = '%.1f';
            app.RotationYSpinner.ValueChangedFcn = createCallbackFcn(app, @RotationYSpinnerValueChanged, true);
            app.RotationYSlider = uislider(app.PointDisplayPanel);
            app.RotationYSlider.Limits = [-90 90];
            app.RotationYSlider.MajorTicks = [-90 0 90];
            app.RotationYSlider.MajorTickLabels = {'[]'};
            app.RotationYSlider.ValueChangedFcn = createCallbackFcn(app, @RotationYSliderValueChanged, true);
            app.RotationYSlider.MinorTicks = [-90 -45 0 45 90];
            app.RotationYLabel = uilabel(app.PointDisplayPanel);
            app.RotationYLabel.Text = '@Y:';
            app.SelectedPointclickonmapLabel = uilabel(app.PointDisplayPanel);
            app.SelectedPointclickonmapLabel.Text = 'Selected Point (click on map):';
            app.FeasibilityThresholdSelectionPanel = uipanel(app.UIFigure);
            app.FeasibilityThresholdSelectionPanel.Title = 'Feasibility Threshold Selection';
            app.ObjectiveCheckBox = uicheckbox(app.FeasibilityThresholdSelectionPanel);
            app.ObjectiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObjectiveCheckBoxValueChanged, true);
            app.ObjectiveCheckBox.Text = 'Objective';
            app.ObjectiveCheckBox.Value = true;
            app.Spinner = uispinner(app.FeasibilityThresholdSelectionPanel);
            app.Spinner.ValueDisplayFormat = '%.2g';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.SliderLabel = uilabel(app.FeasibilityThresholdSelectionPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.FeasibilityThresholdSelectionPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = casting_orientation_optimization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end